classdef appV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AUTOMATICAPanel                matlab.ui.container.Panel
        Spinner_2                      matlab.ui.control.Spinner
        Spinner                        matlab.ui.control.Spinner
        Lamp_2                         matlab.ui.control.Lamp
        GENERARxlsButton               matlab.ui.control.Button
        REPRESENTACIONESGRAFICASPanel  matlab.ui.container.Panel
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        Image3                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        ALGORITMODEFRAGMENTACIONCOMPLEJOQRSMANUALPanel  matlab.ui.container.Panel
        CARGARecgButton                matlab.ui.control.Button
        DERIVACIONDropDown             matlab.ui.control.DropDown
        DERIVACIONDropDownLabel        matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        ArchivoTextArea                matlab.ui.control.TextArea
        ArchivoTextAreaLabel           matlab.ui.control.Label
        INDICADORDEEXISTENCIAPanel     matlab.ui.container.Panel
        DPrecordialesPanel             matlab.ui.container.Panel
        V6Lamp                         matlab.ui.control.Lamp
        V6LampLabel                    matlab.ui.control.Label
        V5Lamp                         matlab.ui.control.Lamp
        V5LampLabel                    matlab.ui.control.Label
        V4Lamp_2                       matlab.ui.control.Lamp
        V4Lamp_2Label                  matlab.ui.control.Label
        V3Lamp_2                       matlab.ui.control.Lamp
        V3Lamp_2Label                  matlab.ui.control.Label
        V2Lamp_2                       matlab.ui.control.Lamp
        V2Lamp_2Label                  matlab.ui.control.Label
        V1Lamp_2                       matlab.ui.control.Lamp
        V1Lamp_2Label                  matlab.ui.control.Label
        DAumentadasPanel               matlab.ui.container.Panel
        aVFLamp                        matlab.ui.control.Lamp
        aVFLampLabel                   matlab.ui.control.Label
        aVLLamp                        matlab.ui.control.Lamp
        aVLLampLabel                   matlab.ui.control.Label
        aVRLamp                        matlab.ui.control.Lamp
        aVRLampLabel                   matlab.ui.control.Label
        DBipolaresPanel                matlab.ui.container.Panel
        IIILampLabel                   matlab.ui.control.Label
        IIILamp                        matlab.ui.control.Lamp
        IILampLabel                    matlab.ui.control.Label
        IILamp                         matlab.ui.control.Lamp
        ILampLabel                     matlab.ui.control.Label
        ILamp                          matlab.ui.control.Lamp
        IDENTIFICACIONDEMORFOLOGIAPanel  matlab.ui.container.Panel
        SegTextArea                    matlab.ui.control.TextArea
        SegTextAreaLabel               matlab.ui.control.Label
        MinTextArea                    matlab.ui.control.TextArea
        MinTextAreaLabel               matlab.ui.control.Label
        RepresentacionMorfologiaPanel  matlab.ui.container.Panel
        Image4                         matlab.ui.control.Image
        MaxTextArea                    matlab.ui.control.TextArea
        MaxTextAreaLabel               matlab.ui.control.Label
        NotchTextArea                  matlab.ui.control.TextArea
        NotchTextAreaLabel             matlab.ui.control.Label
    end

    %::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::
    %:::::::::::::::::::::::::... VARIABLES GLOBALES ...:::::::::::::::::::
    %::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::

    properties (Access = private)

        ID_frag=0;  % ninguna fragmentacion detectada en derivacion definida
        QRS_porcion;% porcion QRS seleccionado e interpolacion por resolucion
        derivacion; % derivacion seleccionada de barra despegable
        QRS_HAAR;   % segmento QRS de tipo HAAR de 2 orden (coef detallados)


    end

    %::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::
    %:::::::::::::::::::::::... METODOS DENTRO GUI ...:::::::::::::::::::::
    %::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::

    methods (Access = private)

        %******************* IDENTIFICACION DE MORFOLOGIA *********************

        function [] = IDMorfologia(app,CDetail)


            app.ID_frag=0;      %resetear indicador
            N=length(CDetail);

            Dminimos=0;
            Dmaximos=0;
            Dnotches=0;
            k_identificado=0;

            %Analisis de maximo (Transicion de - hacia +)
            for k=10:1:N-10

                if(CDetail(1,k)<=0)&&(CDetail(1,k+1)>0)
                    Dmaximos=Dmaximos+1;

                end

            end


            %Analisis de minimo (Transicion de + hacia -)
            for k=10:1:N-10

                if(CDetail(1,k)>0)&&(CDetail(1,k+1)<=0)
                    Dminimos=Dminimos+1;
                    k_identificado=k*2; % Captura posicion donde se identifico

                end

            end


            %muestra de cantidad de valores maximos y minimos detectados
            app.MaxTextArea.Value=num2str(Dmaximos);
            app.MinTextArea.Value=num2str(Dminimos);

            %identificacion y analisis de morfologia

            %clasificacion 1 (tipo diente o tipo M - fragmentacion en R)

            if(Dmaximos==2)&&(Dminimos==1)

                app.ID_frag=1; %detectado

                %tipo M o tipo diente en funcion de amplitud
                amplitud_qrs = app.QRS_porcion(k_identificado);

                if(amplitud_qrs>0)
                    app.Image4.ImageSource='tipoM.png'
                end

                if((amplitud_qrs<0))
                    app.Image4.ImageSource='Ic24.png'
                end

                %clasificacion 2 (tipo diente 2 opcion)======================

            elseif      (Dmaximos==2)&&(Dminimos==2)
                app.ID_frag=1; %detectado
                app.Image4.ImageSource='Ic24.png'

                %clasificacion 3 (no encontrarse)=============================
            else
                app.Image4.ImageSource='INO.png'
                app.ID_frag=0; %no detectado
            end
        end

        %**********************************************************************
        %:::::::::::::::... ACTIVAR INDICADORES DE FRAGMENTACION ...:::::::::::

        function [] = ActivarTestigoFrag(app)

            %::::::::::: desactiva indicadores total derivaciones :::::::::::::::::

            app.ILamp.Color='w';
            app.IILamp.Color='w';
            app.IIILamp.Color='w';
            app.aVRLamp.Color='w';
            app.aVLLamp.Color='w';
            app.aVFLamp.Color='w';
            app.V1Lamp_2.Color='w';
            app.V2Lamp_2.Color='w';
            app.V3Lamp_2.Color='w';
            app.V4Lamp_2.Color='w';
            app.V5Lamp.Color='w';
            app.V6Lamp.Color='w';

            %::::::::::: color de fragmentacion indicada :::::::::::::::::::::::::::

            switch app.ID_frag
                case 0
                    colorin='r';
                case 1
                    colorin='g';
            end

            %::::::::::: activacion de indicador segun derivacion ::::::::::::::::::

            switch app.derivacion
                case 1
                    app.ILamp.Color=colorin;
                case 2
                    app.IILamp.Color=colorin;
                case 3
                    app.IIILamp.Color=colorin;
                case 4
                    app.aVRLamp.Color=colorin;
                case 5
                    app.aVLLamp.Color=colorin;
                case 6
                    app.aVFLamp.Color=colorin;
                case 7
                    app.V1Lamp_2.Color=colorin;
                case 8
                    app.V2Lamp_2.Color=colorin;
                case 9
                    app.V3Lamp_2.Color=colorin;
                case 10
                    app.V4Lamp_2.Color=colorin;
                case 11
                    app.V5Lamp.Color=colorin;
                case 12
                    app.V6Lamp.Color=colorin;
            end

        end
        %****************************************************************************
        %================ ACTUALIZACION DE GRAFICO 3 - SEGMENTO QRS ================

        function [] = grafico3(app)

            x=1:1:length(app.QRS_porcion);  % linea basal referencial
            minx = min(x); maxx = max(x);
            plot(app.UIAxes3,[minx,maxx],[0,0],'color','#7E2F8E','LineWidth',2);
            hold(app.UIAxes3,'on');

            plot(app.UIAxes3,app.QRS_porcion);
            grid(app.UIAxes3,"on");
            grid(app.UIAxes3,"minor");
            axis(app.UIAxes3,"tight");
            hold(app.UIAxes3,'off');

        end

        %***************************************************************************
        %============== ACTUALIZACION DE GRAFICO 4 - SEGMENTO QRS (HAAR) ==========

        function [] = grafico4(app)

            x1=1:1:length(app.QRS_HAAR);        % linea basal referencial
            minx1 = min(x1); maxx1 = max(x1);
            plot(app.UIAxes4,[minx1,maxx1],[0,0],'color','#7E2F8E','LineWidth',2)
            hold(app.UIAxes4,'on');

            bar(app.UIAxes4,app.QRS_HAAR);
            grid(app.UIAxes4,"on");
            grid(app.UIAxes4,"minor");
            axis(app.UIAxes4,"tight");
            hold(app.UIAxes4,'off');

        end

        %***************************************************************************
        %=============== ACTUALIZACION DE GRAFICO 2 - SEÑAL ECG PROCESADA =========

        function [] = grafico2(app)

            load 'pre-ecg7_QRS_detection.mat'
            load 'pre-ecg7_ECG_delineation.mat'
            load 'parametros.mat'

            QRSon = wavedet.multilead.QRSon; %segmentacion QRS 1
            QRSoff= wavedet.multilead.QRSoff;%segmentacion QRS 2
            QRSoff=QRSoff';
            QRSon=QRSon';

            switch app.derivacion
                case 1
                    tp = pantom_I.time; %marcador tompankins
                    signal_estrella=signal(:,1);
                case 2
                    tp = pantom_II.time; %marcador tompankins
                    signal_estrella=signal(:,2);
                case 3
                    tp = pantom_III.time; %marcador tompankins
                    signal_estrella=signal(:,3);
                case 4
                    %excepcion analisis inverso
                    tp = pantom_aVR.time; %marcador tompankins
                    signal_estrella=signal(:,4);
                    signal_estrella=-(signal_estrella);
                case 5
                    tp = pantom_aVL.time; %marcador tompankins
                    signal_estrella=signal(:,5);
                case 6
                    tp = pantom_aVF.time; %marcador tompankins
                    signal_estrella=signal(:,6);
                case 7
                    tp = pantom_V1.time; %marcador tompankins
                    signal_estrella=signal(:,7);
                case 8
                    tp = pantom_V2.time; %marcador tompankins
                    signal_estrella=signal(:,8);
                case 9
                    tp = pantom_V3.time; %marcador tompankins
                    signal_estrella=signal(:,9);
                case 10
                    tp = pantom_V4.time; %marcador tompankins
                    signal_estrella=signal(:,10);
                case 11
                    tp = pantom_V5.time; %marcador tompankins
                    signal_estrella=signal(:,11);
                case 12
                    tp = pantom_V6.time; %marcador tompankins
                    signal_estrella=signal(:,12);
            end


            Nb=length(QRSon);                   %Numero de beats detectados
            m=max(signal_estrella);
            mi=min(signal_estrella);
            pp=[m*ones(1,Nb);mi*ones(1,Nb)];    %Para plotear rectas verticales entre mi y m
            Qplot = [QRSon;QRSon];
            Qplot2 = [QRSoff;QRSoff];

            x2=1:1:length(signal_estrella);     %linea de referencia basal
            x2=x2/fs;
            minx2 = min(x2); maxx2 = max(x2);
            plot(app.UIAxes2,[minx2,maxx2],[0,0],'Color',[0.4940 0.1840 0.5560],'LineWidth',2)
            hold(app.UIAxes2,'on');

            plot(app.UIAxes2,t,signal_estrella,'K',tp/fs,m,'bh',Qplot/fs,pp,'b--',Qplot2/fs,pp,'r--')
            xlabel(app.UIAxes2,'tiempo (s)')
            grid(app.UIAxes2,"on");
            grid(app.UIAxes2,"minor");
            axis(app.UIAxes2,"tight");
            hold(app.UIAxes2,'off');

        end

        %***********************************************************************************************
        %=========================== GRAFICO 1 - SEÑAL ORIGINAL ==================

        function [] = grafico1(app)

            load 'parametros.mat'

            switch app.derivacion
                case 1
                    plot(app.UIAxes,ECGs_original(:,app.derivacion))
                case 2
                    plot(app.UIAxes,ECGs_original(:,app.derivacion))
                case 3
                    plot(app.UIAxes,ECGs_original(:,app.derivacion))
                case 4
                    plot(app.UIAxes,ECGs_original(:,app.derivacion))
                case 5
                    plot(app.UIAxes,ECGs_original(:,app.derivacion))
                case 6
                    plot(app.UIAxes,ECGs_original(:,app.derivacion))
                case 7
                    plot(app.UIAxes,ECGs_original(:,app.derivacion))
                case 8
                    plot(app.UIAxes,ECGs_original(:,app.derivacion))
                case 9
                    plot(app.UIAxes,ECGs_original(:,app.derivacion))
                case 10
                    plot(app.UIAxes,ECGs_original(:,app.derivacion))
                case 11
                    plot(app.UIAxes,ECGs_original(:,app.derivacion))
                case 12
                    plot(app.UIAxes,ECGs_original(:,app.derivacion))
            end

            grid(app.UIAxes,"on");
            grid(app.UIAxes,"minor");
            axis(app.UIAxes,"tight");

        end


        %***************************************************************************************

        %***************************************************************************************
        %***************************************************************************************
        %***************************************************************************************
        %***************************************************************************************
        %***************************************************************************************
        %***************************************************************************************


        %***************************************************************************************

        function [CDetail,interpolated_qrs, segmento]= morfologia_HAAR(app, indice)

            load ('pre-ecg7_QRS_detection.mat');
            load ('pre-ecg7_ECG_delineation.mat');
            load ('pre-ecg7.mat');

            %todas derivaciones operan con un unico QRS definido por multilead
            referencia1=(wavedet.multilead.QRSon)';
            referencia2=(wavedet.multilead.QRSoff)';

            %algoritmo de eleccion de mejor referencia QRS proximo a promedio
            periodoQRS=referencia2-referencia1;
            temporal=abs(periodoQRS-mean(periodoQRS));
            temporal2=min(temporal<5,[],1);

            %save temporal2.mat temporal2 periodoQRS

            p=length(temporal2);
            segmento=0;
            %Se elige ultimo segmento que cumple condicion
            for i=1:1:p

                if(temporal2(i)==1)
                    segmento=i;
                end

            end

            if(segmento==0)
                segmento=5;
            end
            %save temporal2.mat temporal2 periodoQRS segmento

            qrs=signal(referencia1(1,segmento):referencia2(1,segmento),indice); %segmento 3

            if(indice==4)
                qrs=-qrs;
            end


            %Interpolacion para incrementar resolucion de analisis
            L=length(qrs);
            z=1;
            interpolated_qrs=zeros(1,2*L-1);

            for k=1:1:L
                if k<L
                    w=[qrs(k),mean([qrs(k),qrs(k+1)]),qrs(k+1)];
                    interpolated_qrs(z)=w(1);
                    interpolated_qrs(z+1)=w(2);
                    z=z+2;
                end
            end

            %Transformada wavelet tipo HAAR de 2 niveles
            [Cprom,CDetail]=dwt(interpolated_qrs,"haar");


        end
        %*****************************
        function [signal,N,t,fs,ECGs_original] = pre_procesamientoECG(app,signalECG12)
            %==========================================================================
            % Funcion que permite realizar el pre-procesamiento de una señal ECG,
            % basado en las siguientes caracteristicas:
            %
            %
            % Usando libreria ECG KIT
            %==========================================================================

            ECG=signalECG12; %intercambio de contenido pseudonimo

            %lectura de objeto ECG en totalidad muestras
            cantidad.muestras=ECG.ECG_header.nsamp;
            ECGs = ECG.read_signal(1,cantidad.muestras);

            %Ajuste de resolucion y unidades de señal ECG
            % Output in ADC units and int16 format
            ECGs = double(ECGs);   % signals in int16 --> float
            ECGs_original=ECGs;
            gain = ECG.ECG_header.gain; % gain=4e-4 -> 1/2500 (Resolution = 2500 nV)
            fs = ECG.ECG_header.freq; % Sampling frequency
            baseline= ECG.ECG_header.adczero;
            [N,leads]=size(ECGs);
            %Convert to Physical units (by default nV)
            for i=1:leads
                ECGs(:,i) = (ECGs(:,i)-baseline(i))/gain(i);
            end
            % Units in nV (nanoVolts) --> mV (*1e-6)
            ECGs = ECGs.*1e-6;   % units in mV

            %eliminacion de offset en canales ECG (offset=0)
            ECGs = detrend(ECGs);
            %creacion de vector de tiempo para normalizacion
            t=(1:N)/fs';

            %eliminacion de ruido de lineabase de tipo wander presente en ECG
            %uso de filtro de tipo recursivo IIR de fase 0 (Ida y vuelta)
            % Filtro pasa alto IIR Butterworth orden 3, fc = 0.3 Hz, forward-backward

            fN = fs/2;  %frecuencia de nyquist
            fc1=0.6;  %frecuencia de corte

            % filtro en 1 sentido
            [bfpa,afpa]=butter(3,fc1/fN,'high');
            signal=filtfilt(bfpa,afpa,ECGs);
            % filtro en 2 sentido
            [bfpb,afpb]=butter(3,40/fN);
            signal=filtfilt(bfpb,afpb,signal);

            %vector signal contiene las 12 derivaciones de señal ECG
            N= length(signal);
            %creacion de vector de tiempo para normalizacion
            t=(1:N)/fs';

            %almacen señal ECG de 12 derivaciones pre-procesada
            header=ECG.ECG_header;
            header.nsamp=N;
            delete('pre-ecg7.mat')

            save pre-ecg7.mat signal header  %salvado

            %################ IMPORTANTE ##############################################
            %muestra de señales normalizadas en orden de mV (Milivoltios)
            %Objeto de tipo vector ECGs(filas, columnas) contiene :
            % filas = cantidad de muestras
            % columnas = canales ECG (7-precordial v1, 8-precordial v2, 9-precordial v3
            % para analisis de sindrome de brugada nos centramos en los 3 ...
            % precordiales derechos v1, v2 y v3.
            %##########################################################################


        end


        %****

        function [] = deteccion_ECG(app)

            delete('pre-ecg7_QRS_detection.mat');

            ECG2 = ECGwrapper('recording_name','pre-ecg7.mat','output_path','F:\Ingenieria Biomedica - VIU\Codigo Brugada');
            ECG2.ECGtaskHandle = 'QRS_detection';
            ECG2.ECGtaskHandle.detectors = {'pantom'};
            ECG2.Run;

        end
        %******
        function [] = segmentacion_ECG(app)

            delete('pre-ecg7_ECG_delineation.mat');

            %%

            %Delineacion empleando marcado tipo wavedet - Martinez 2004 TBM - IEEE
            ECG = ECGwrapper('recording_name','pre-ecg7.mat','output_path','F:\Ingenieria Biomedica - VIU\Codigo Brugada');
            ECG.ECGtaskHandle = 'ECG_delineation';
            ECG.Run;


        end


        %***********************
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CARGARecgButton
        function CARGARECGButtonPushed(app, event)

            %***************************** BOTON DE CARGA PRINCIPAL *********************
            % Carga el archivo ECG y realizar el procesamiento de la señal en 3 etapas:
            % - etapa 1 - pre-procesamiento señal ECG (eliminacion de ruido (detrend) y
            % correccion de linea basal
            % - etapa 2 - deteccion de latidos ECG para el procesamiento posterior
            % - etapa 3 - segmentacion señal ECG en porciones (onda P, onda T, complejo QRS)
            %****************************************************************************
            app.Lamp.Color='r';

            %Lectura de archivo
            [f, p] = uigetfile('F:\Ingenieria Biomedica - VIU\Codigo Brugada\Signal ECG\*.ecg', 'Seleccionar ECG Referencia');
            x = fullfile(p,f);

            %creacion de objeto tipo ECG
            ECG1 = ECGwrapper('recording_name',x);
            save ECG1.mat ECG1

            %Etapa 1 - PRE PROCESAMIENTO - ECG
            [signal,N,t,fs, ECGs_original] = pre_procesamientoECG(app,ECG1);
            save parametros.mat fs signal ECG1 N t ECGs_original

            %Etapa 2 - DETECCION DE LATIDOS - ECG
            deteccion_ECG(app);

            %Etapa 3 - SEGMENTACION - ECG
            segmentacion_ECG(app);

            %Indicador culmino 3 etapas principales
            app.Lamp.Color='g';
            app.ArchivoTextArea.Value=(f);

        end

        % Value changed function: DERIVACIONDropDown
        function DERIVACIONDropDownValueChanged(app, event)
            %************************** BOTON DE CARGA AUXILIAR ************************
            % Permite la representacion de 4 graficos:
            % - grafico1: representacion de señal ECG original
            % - grafico2: representacion de señal ECG procesada
            % - grafico3: representacion de porcion ECG seleccionada
            % - grafico4: representacion de porcion ECG HAAR

            % Analisis de Transformada HAAR previo al grafico4
            %***************************************************************************
            app.derivacion=app.DERIVACIONDropDown.Value;


            %Analisis y Representacion barras coeficientes detallados
            %Transformada HAAR - WAVELET
            [CDetallados, QRS_seg,segmento] =morfologia_HAAR(app,app.derivacion);
            app.SegTextArea.Value=num2str(segmento);
            app.QRS_porcion=QRS_seg;
            app.QRS_HAAR=CDetallados;

            %Grafico 1 - Señal ECG Original con ruido
            grafico1(app);

            %Grafico 2 - Señal ECG Pre-Procesada
            grafico2(app);

            %Grafico 3 - Segmento QRS (3 preseleccionado)
            grafico3(app);


            %Grafico 4 - Representacion de QRS HAAR (Coeficientes
            %detallados)
            grafico4(app)

            %Representacion de morfologia
            IDMorfologia(app,app.QRS_HAAR);
            ActivarTestigoFrag(app);

        end

        % Button pushed function: GENERARxlsButton
        function GENERARxlsButtonPushed(app, event)
            % ********************* BOTON DE ANALISIS AUTOMATICO ***********************
            % Boton que permite realizar el analisis automatico de identificacion de fragmentacion
            % en las 26 señales dentro de las 12 derivaciones, generando un documento
            % de reporte en formato (*.excel) que permitira evaluar la efectividad global del algoritmo.
            %**************************************************************************
            app.Lamp_2.Color='r';   % indicador de generacion de documento

            texto='paciente-';
            texto2='.ecg'

            p='F:\Ingenieria Biomedica - VIU\Codigo Brugada\Signal ECG\'

            %matriz vacia de analisis total de fragmentacion
            %26 filas de señales x 12 columnas de derivaciones
            analisisT=zeros(26,12);

            i=app.Spinner.Value;
            f=app.Spinner_2.Value;

            for paciente=i:1:f
                for derivacion=1:1:12

                    %*******************************


                    textin=[texto num2str(paciente) texto2];
                    x = fullfile(p,textin)


                    %creacion de objeto tipo ECG
                    ECG1 = ECGwrapper('recording_name',x);

                    %Etapa 1 - PRE PROCESAMIENTO - ECG
                    [signal,N,t,fs, ECGs_original] = pre_procesamientoECG(app,ECG1);
                    save parametros.mat fs signal ECG1 N t ECGs_original

                    %Etapa 2 - DETECCION DE LATIDOS - ECG
                    deteccion_ECG(app);

                    %Etapa 3 - SEGMENTACION - ECG
                    segmentacion_ECG(app);

                    %Analisis y Representacion barras coeficientes detallados
                    %Transformada HAAR - WAVELET
                    [CDetallados, QRS_seg,segmento] =morfologia_HAAR(app,derivacion);

                    app.QRS_porcion=QRS_seg;
                    app.QRS_HAAR=CDetallados;
                    IDMorfologia(app,app.QRS_HAAR);
                    analisisT(paciente,derivacion)=app.ID_frag;


                    %******************************
                end
            end

            %proceso final de escritura
            T=table(analisisT);
            writetable(T,'formato.xlsm','Range','E8','WriteVariableNames', false);


            %*****************************************************************************


            app.Lamp_2.Color='g';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IDENTIFICACIONDEMORFOLOGIAPanel = uipanel(app.UIFigure);
            app.IDENTIFICACIONDEMORFOLOGIAPanel.Title = 'IDENTIFICACION DE MORFOLOGIA';
            app.NotchTextAreaLabel = uilabel(app.IDENTIFICACIONDEMORFOLOGIAPanel);
            app.NotchTextAreaLabel.Text = 'Notch';
            app.NotchTextArea = uitextarea(app.IDENTIFICACIONDEMORFOLOGIAPanel);
            app.MaxTextAreaLabel = uilabel(app.IDENTIFICACIONDEMORFOLOGIAPanel);
            app.MaxTextAreaLabel.Text = 'Max';
            app.MaxTextArea = uitextarea(app.IDENTIFICACIONDEMORFOLOGIAPanel);
            app.RepresentacionMorfologiaPanel = uipanel(app.IDENTIFICACIONDEMORFOLOGIAPanel);
            app.RepresentacionMorfologiaPanel.Title = 'Representacion Morfologia';
            app.Image4 = uiimage(app.RepresentacionMorfologiaPanel);
            app.Image4.ImageSource = 'INO.png';
            app.MinTextAreaLabel = uilabel(app.IDENTIFICACIONDEMORFOLOGIAPanel);
            app.MinTextAreaLabel.Text = 'Min';
            app.MinTextArea = uitextarea(app.IDENTIFICACIONDEMORFOLOGIAPanel);
            app.SegTextAreaLabel = uilabel(app.IDENTIFICACIONDEMORFOLOGIAPanel);
            app.SegTextAreaLabel.Text = 'Seg';
            app.SegTextArea = uitextarea(app.IDENTIFICACIONDEMORFOLOGIAPanel);
            app.INDICADORDEEXISTENCIAPanel = uipanel(app.UIFigure);
            app.INDICADORDEEXISTENCIAPanel.Title = 'INDICADOR DE EXISTENCIA';
            app.DBipolaresPanel = uipanel(app.INDICADORDEEXISTENCIAPanel);
            app.DBipolaresPanel.Title = 'D. Bipolares';
            app.ILamp = uilamp(app.DBipolaresPanel);
            app.ILampLabel = uilabel(app.DBipolaresPanel);
            app.ILampLabel.Text = 'I';
            app.IILamp = uilamp(app.DBipolaresPanel);
            app.IILampLabel = uilabel(app.DBipolaresPanel);
            app.IILampLabel.Text = 'II';
            app.IIILamp = uilamp(app.DBipolaresPanel);
            app.IIILampLabel = uilabel(app.DBipolaresPanel);
            app.IIILampLabel.Text = 'III';
            app.DAumentadasPanel = uipanel(app.INDICADORDEEXISTENCIAPanel);
            app.DAumentadasPanel.Title = 'D. Aumentadas';
            app.aVRLampLabel = uilabel(app.DAumentadasPanel);
            app.aVRLampLabel.Text = 'aVR';
            app.aVRLamp = uilamp(app.DAumentadasPanel);
            app.aVLLampLabel = uilabel(app.DAumentadasPanel);
            app.aVLLampLabel.Text = 'aVL';
            app.aVLLamp = uilamp(app.DAumentadasPanel);
            app.aVFLampLabel = uilabel(app.DAumentadasPanel);
            app.aVFLampLabel.Text = 'aVF';
            app.aVFLamp = uilamp(app.DAumentadasPanel);
            app.DPrecordialesPanel = uipanel(app.INDICADORDEEXISTENCIAPanel);
            app.DPrecordialesPanel.Title = 'D. Pre - cordiales';
            app.V1Lamp_2Label = uilabel(app.DPrecordialesPanel);
            app.V1Lamp_2Label.Text = 'V1';
            app.V1Lamp_2 = uilamp(app.DPrecordialesPanel);
            app.V2Lamp_2Label = uilabel(app.DPrecordialesPanel);
            app.V2Lamp_2Label.Text = 'V2';
            app.V2Lamp_2 = uilamp(app.DPrecordialesPanel);
            app.V3Lamp_2Label = uilabel(app.DPrecordialesPanel);
            app.V3Lamp_2Label.Text = 'V3';
            app.V3Lamp_2 = uilamp(app.DPrecordialesPanel);
            app.V4Lamp_2Label = uilabel(app.DPrecordialesPanel);
            app.V4Lamp_2Label.Text = 'V4';
            app.V4Lamp_2 = uilamp(app.DPrecordialesPanel);
            app.V5LampLabel = uilabel(app.DPrecordialesPanel);
            app.V5LampLabel.Text = 'V5';
            app.V5Lamp = uilamp(app.DPrecordialesPanel);
            app.V6LampLabel = uilabel(app.DPrecordialesPanel);
            app.V6LampLabel.Text = 'V6';
            app.V6Lamp = uilamp(app.DPrecordialesPanel);
            app.ALGORITMODEFRAGMENTACIONCOMPLEJOQRSMANUALPanel = uipanel(app.UIFigure);
            app.ALGORITMODEFRAGMENTACIONCOMPLEJOQRSMANUALPanel.Title = 'ALGORITMO DE FRAGMENTACION - COMPLEJO QRS (MANUAL)';
            app.ArchivoTextAreaLabel = uilabel(app.ALGORITMODEFRAGMENTACIONCOMPLEJOQRSMANUALPanel);
            app.ArchivoTextAreaLabel.Text = 'Archivo: ';
            app.ArchivoTextArea = uitextarea(app.ALGORITMODEFRAGMENTACIONCOMPLEJOQRSMANUALPanel);
            app.Lamp = uilamp(app.ALGORITMODEFRAGMENTACIONCOMPLEJOQRSMANUALPanel);
            app.DERIVACIONDropDownLabel = uilabel(app.ALGORITMODEFRAGMENTACIONCOMPLEJOQRSMANUALPanel);
            app.DERIVACIONDropDownLabel.Text = 'DERIVACION';
            app.DERIVACIONDropDown = uidropdown(app.ALGORITMODEFRAGMENTACIONCOMPLEJOQRSMANUALPanel);
            app.DERIVACIONDropDown.Items = {'I', 'II', 'III', 'aVR', 'aVL', 'aVF', 'V1', 'V2', 'V3', 'V4', 'V5', 'V6'};
            app.DERIVACIONDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12];
            app.DERIVACIONDropDown.ValueChangedFcn = createCallbackFcn(app, @DERIVACIONDropDownValueChanged, true);
            app.DERIVACIONDropDown.Value = 1;
            app.CARGARecgButton = uibutton(app.ALGORITMODEFRAGMENTACIONCOMPLEJOQRSMANUALPanel, 'push');
            app.CARGARecgButton.ButtonPushedFcn = createCallbackFcn(app, @CARGARECGButtonPushed, true);
            app.CARGARecgButton.Text = 'CARGAR (*.ecg)';
            app.Panel = uipanel(app.UIFigure);
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = 'Logo_VIU.png';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'BRAUN.png';
            app.Image3 = uiimage(app.Panel);
            app.Image3.ImageSource = 'INGENIO.png';
            app.REPRESENTACIONESGRAFICASPanel = uipanel(app.UIFigure);
            app.REPRESENTACIONESGRAFICASPanel.Title = 'REPRESENTACIONES GRAFICAS';
            app.UIAxes = uiaxes(app.REPRESENTACIONESGRAFICASPanel);
            title(app.UIAxes, 'SEÑAL ECG (SIN PRE-PROCESAMIENTO)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.REPRESENTACIONESGRAFICASPanel);
            title(app.UIAxes2, 'SEÑAL ECG (PRE-PROCESAMIENTO)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.REPRESENTACIONESGRAFICASPanel);
            title(app.UIAxes3, 'SEGMENTO QRS')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.REPRESENTACIONESGRAFICASPanel);
            title(app.UIAxes4, 'HAAR WAVELET - COEFICIENTES DETALLADOS')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.AUTOMATICAPanel = uipanel(app.UIFigure);
            app.AUTOMATICAPanel.Title = 'AUTOMATICA';
            app.GENERARxlsButton = uibutton(app.AUTOMATICAPanel, 'push');
            app.GENERARxlsButton.ButtonPushedFcn = createCallbackFcn(app, @GENERARxlsButtonPushed, true);
            app.GENERARxlsButton.Text = 'GENERAR (*.xls)';
            app.Lamp_2 = uilamp(app.AUTOMATICAPanel);
            app.Spinner = uispinner(app.AUTOMATICAPanel);
            app.Spinner.Limits = [1 25];
            app.Spinner.Value = 1;
            app.Spinner_2 = uispinner(app.AUTOMATICAPanel);
            app.Spinner_2.Limits = [3 26];
            app.Spinner_2.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end